classdef RobotControllerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        RunbothButton         matlab.ui.control.Button
        Link7Slider_2         matlab.ui.control.Slider
        Link7Slider_2Label    matlab.ui.control.Label
        Link6Slider_2         matlab.ui.control.Slider
        Link6Slider_2Label    matlab.ui.control.Label
        Link5Slider_2         matlab.ui.control.Slider
        Link5Slider_2Label    matlab.ui.control.Label
        Link4Slider_2         matlab.ui.control.Slider
        Link4Slider_2Label    matlab.ui.control.Label
        Link3Slider_2         matlab.ui.control.Slider
        Link3Slider_2Label    matlab.ui.control.Label
        Link2Slider_2         matlab.ui.control.Slider
        Link2Slider_2Label    matlab.ui.control.Label
        Link1Slider_2         matlab.ui.control.Slider
        Link1Slider_2Label    matlab.ui.control.Label
        PositionLabel_2       matlab.ui.control.Label
        Image_2               matlab.ui.control.Image
        ABB2600Label          matlab.ui.control.Label
        RunButton_3           matlab.ui.control.Button
        Image                 matlab.ui.control.Image
        LinearUR10Label       matlab.ui.control.Label
        RunButton_2           matlab.ui.control.Button
        Link7Slider           matlab.ui.control.Slider
        Link7SliderLabel      matlab.ui.control.Label
        PositionLabel         matlab.ui.control.Label
        Image2                matlab.ui.control.Image
        TwinTechAutoSpaLabel  matlab.ui.control.Label
        Lamp_4                matlab.ui.control.Lamp
        Lamp_3                matlab.ui.control.Lamp
        Lamp_2                matlab.ui.control.Lamp
        EmergencyStopLabel    matlab.ui.control.Label
        Link6Slider           matlab.ui.control.Slider
        Link6SliderLabel      matlab.ui.control.Label
        Link5Slider           matlab.ui.control.Slider
        Link5Label            matlab.ui.control.Label
        Link4Slider           matlab.ui.control.Slider
        Link4SliderLabel      matlab.ui.control.Label
        Link3Slider           matlab.ui.control.Slider
        Link3SliderLabel      matlab.ui.control.Label
        Link2Slider           matlab.ui.control.Slider
        Link2SliderLabel      matlab.ui.control.Label
        Link1Slider           matlab.ui.control.Slider
        Link1SliderLabel      matlab.ui.control.Label
        Button                matlab.ui.control.Button
    end

    
    properties (Access = public)
        robot;
        robot2;
        qInitUR10 = [-0.4 0 0 0 0 0 0];
        qInitABB = [-0.4 0 0 0 0 0 0];
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Callback function: not associated with a component
        function RunButtonPushed(app, event)


        end

        % Button pushed function: Button
        function ButtonPushed(app, event)


        if strcmpi(app.Button.Text,'Shut Down')
            app.Button.Text = 'STOP';
            app.RunbothButton.Text = 'Run';
            app.Lamp_2.Color = [1,0,0];
            app.Lamp_3.Color = [1,1,1];
        end
            
        if strcmpi(app.RunbothButton.Enable,'off')
            set(app.RunbothButton,'Enable','on')
            app.Lamp_2.Color = [1,1,1];
            app.Lamp_3.Color = [1,1,0];
            app.Lamp_4.Color = [1,1,1];
            app.Button.Text = 'Shut Down';
            app.RunbothButton.Text = 'Continue';
        end


        end

        % Value changed function: Link1Slider
        function Link1SliderValueChanged(app, event)
      

            q = [app.Link1Slider.Value, app.Link2Slider.Value, app.Link3Slider.Value,app.Link4Slider.Value,app.Link5Slider.Value,app.Link6Slider.Value, app.Link7Slider.Value]
                

                    app.robot.model.animate(q)
      
      
        end

        % Value changed function: Link2Slider
        function Link2SliderValueChanged(app, event)
    
            q = [app.Link1Slider.Value, app.Link2Slider.Value, app.Link3Slider.Value,app.Link4Slider.Value,app.Link5Slider.Value,app.Link6Slider.Value, app.Link7Slider.Value];
                

                    app.robot.model.animate(q)
     
        end

        % Value changed function: Link3Slider
        function Link3SliderValueChanged(app, event)
            q = [app.Link1Slider.Value, app.Link2Slider.Value, app.Link3Slider.Value,app.Link4Slider.Value,app.Link5Slider.Value,app.Link6Slider.Value, app.Link7Slider.Value];
                

                    app.robot.model.animate(q)
        end

        % Value changed function: Link4Slider
        function Link4SliderValueChanged(app, event)
            q = [app.Link1Slider.Value, app.Link2Slider.Value, app.Link3Slider.Value,app.Link4Slider.Value,app.Link5Slider.Value,app.Link6Slider.Value, app.Link7Slider.Value];
                

                    app.robot.model.animate(q)
        end

        % Value changed function: Link5Slider
        function Link5SliderValueChanged(app, event)
             q = [app.Link1Slider.Value, app.Link2Slider.Value, app.Link3Slider.Value,app.Link4Slider.Value,app.Link5Slider.Value,app.Link6Slider.Value, app.Link7Slider.Value];
                

                    app.robot.model.animate(q)
        end

        % Value changed function: Link6Slider
        function Link6SliderValueChanged(app, event)
            q = [app.Link1Slider.Value, app.Link2Slider.Value, app.Link3Slider.Value,app.Link4Slider.Value,app.Link5Slider.Value,app.Link6Slider.Value, app.Link7Slider.Value];
                

                    app.robot.model.animate(q)
        end

        % Value changed function: Link7Slider
        function Link7SliderValueChanged(app, event)
            q = [app.Link1Slider.Value, app.Link2Slider.Value, app.Link3Slider.Value,app.Link4Slider.Value,app.Link5Slider.Value,app.Link6Slider.Value, app.Link7Slider.Value];
                

                    app.robot.model.animate(q)
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
            app.robot = LinearUR10;
            try
                app.robot.model.animate(app.qInitUR10);
            catch
            end
            
            %app.robot.PlotAndColourRobot();
            
         
        end

        % Button pushed function: RunButton_3
        function RunButton_3Pushed(app, event)
            app.robot2 = LinearABB;
             try
                app.robot2.model.animate(app.qInitABB);
            catch
            end
            %app.robot2.PlotAndColourRobot();

       
        end

        % Value changed function: Link1Slider_2
        function Link1Slider_2ValueChanged(app, event)
 q = [app.Link1Slider_2.Value, app.Link2Slider_2.Value, app.Link3Slider_2.Value,app.Link4Slider_2.Value,app.Link5Slider_2.Value,app.Link6Slider_2.Value, app.Link7Slider_2.Value]
                

                    app.robot2.model.animate(q)
      
        end

        % Value changed function: Link2Slider_2
        function Link2Slider_2ValueChanged(app, event)
 q = [app.Link1Slider_2.Value, app.Link2Slider_2.Value, app.Link3Slider_2.Value,app.Link4Slider_2.Value,app.Link5Slider_2.Value,app.Link6Slider_2.Value, app.Link7Slider_2.Value];
                

                    app.robot2.model.animate(q)
      
        end

        % Value changed function: Link3Slider_2
        function Link3Slider_2ValueChanged(app, event)
 q = [app.Link1Slider_2.Value, app.Link2Slider_2.Value, app.Link3Slider_2.Value,app.Link4Slider_2.Value,app.Link5Slider_2.Value,app.Link6Slider_2.Value, app.Link7Slider_2.Value];
                

                    app.robot2.model.animate(q)
      
        end

        % Value changed function: Link4Slider_2
        function Link4Slider_2ValueChanged(app, event)
 q = [app.Link1Slider_2.Value, app.Link2Slider_2.Value, app.Link3Slider_2.Value,app.Link4Slider_2.Value,app.Link5Slider_2.Value,app.Link6Slider_2.Value, app.Link7Slider_2.Value];
                

                    app.robot2.model.animate(q)
      
        end

        % Value changed function: Link5Slider_2
        function Link5Slider_2ValueChanged(app, event)
  q = [app.Link1Slider_2.Value, app.Link2Slider_2.Value, app.Link3Slider_2.Value,app.Link4Slider_2.Value,app.Link5Slider_2.Value,app.Link6Slider_2.Value, app.Link7Slider_2.Value];
                

                    app.robot2.model.animate(q)
      
        end

        % Value changed function: Link6Slider_2
        function Link6Slider_2ValueChanged(app, event)
 q = [app.Link1Slider_2.Value, app.Link2Slider_2.Value, app.Link3Slider_2.Value,app.Link4Slider_2.Value,app.Link5Slider_2.Value,app.Link6Slider_2.Value, app.Link7Slider_2.Value];
                

                    app.robot2.model.animate(q)
      
        end

        % Value changed function: Link7Slider_2
        function Link7Slider_2ValueChanged(app, event)
 q = [app.Link1Slider_2.Value, app.Link2Slider_2.Value, app.Link3Slider_2.Value,app.Link4Slider_2.Value,app.Link5Slider_2.Value,app.Link6Slider_2.Value, app.Link7Slider_2.Value];
                

                    app.robot2.model.animate(q)
      
        end

        % Button pushed function: RunbothButton
        function RunbothButtonPushed(app, event)
            app.Lamp_2.Color = [1,1,1];
            app.Lamp_3.Color = [1,1,1];
            set(app.RunbothButton,'Enable','off')
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'button-161502_1280.png';
            app.Button.Text = '';
            app.Link1SliderLabel = uilabel(app.UIFigure);
            app.Link1SliderLabel.Text = 'Link 1';
            app.Link1Slider = uislider(app.UIFigure);
            app.Link1Slider.Limits = [-3.8 -0.01];
            app.Link1Slider.ValueChangedFcn = createCallbackFcn(app, @Link1SliderValueChanged, true);
            app.Link1Slider.Value = -0.01;
            app.Link2SliderLabel = uilabel(app.UIFigure);
            app.Link2SliderLabel.Text = 'Link 2';
            app.Link2Slider = uislider(app.UIFigure);
            app.Link2Slider.Limits = [-360 360];
            app.Link2Slider.ValueChangedFcn = createCallbackFcn(app, @Link2SliderValueChanged, true);
            app.Link3SliderLabel = uilabel(app.UIFigure);
            app.Link3SliderLabel.Text = 'Link 3';
            app.Link3Slider = uislider(app.UIFigure);
            app.Link3Slider.Limits = [-90 90];
            app.Link3Slider.ValueChangedFcn = createCallbackFcn(app, @Link3SliderValueChanged, true);
            app.Link4SliderLabel = uilabel(app.UIFigure);
            app.Link4SliderLabel.Text = 'Link 4';
            app.Link4Slider = uislider(app.UIFigure);
            app.Link4Slider.Limits = [-170 170];
            app.Link4Slider.ValueChangedFcn = createCallbackFcn(app, @Link4SliderValueChanged, true);
            app.Link5Label = uilabel(app.UIFigure);
            app.Link5Label.Text = 'Link 5';
            app.Link5Slider = uislider(app.UIFigure);
            app.Link5Slider.Limits = [-360 360];
            app.Link5Slider.ValueChangedFcn = createCallbackFcn(app, @Link5SliderValueChanged, true);
            app.Link6SliderLabel = uilabel(app.UIFigure);
            app.Link6SliderLabel.Text = 'Link 6';
            app.Link6Slider = uislider(app.UIFigure);
            app.Link6Slider.Limits = [-360 360];
            app.Link6Slider.ValueChangedFcn = createCallbackFcn(app, @Link6SliderValueChanged, true);
            app.EmergencyStopLabel = uilabel(app.UIFigure);
            app.EmergencyStopLabel.Text = 'Emergency Stop';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_4 = uilamp(app.UIFigure);
            app.TwinTechAutoSpaLabel = uilabel(app.UIFigure);
            app.TwinTechAutoSpaLabel.Text = 'Twin Tech Auto Spa';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'LogoDroplets.png';
            app.PositionLabel = uilabel(app.UIFigure);
            app.PositionLabel.Text = 'Position';
            app.Link7SliderLabel = uilabel(app.UIFigure);
            app.Link7SliderLabel.Text = 'Link 7';
            app.Link7Slider = uislider(app.UIFigure);
            app.Link7Slider.Limits = [-360 360];
            app.Link7Slider.ValueChangedFcn = createCallbackFcn(app, @Link7SliderValueChanged, true);
            app.RunButton_2 = uibutton(app.UIFigure, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.LinearUR10Label = uilabel(app.UIFigure);
            app.LinearUR10Label.Text = 'LinearUR10';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Scrub-Daddy-Emblem.png';
            app.RunButton_3 = uibutton(app.UIFigure, 'push');
            app.RunButton_3.ButtonPushedFcn = createCallbackFcn(app, @RunButton_3Pushed, true);
            app.RunButton_3.Text = 'Run';
            app.ABB2600Label = uilabel(app.UIFigure);
            app.ABB2600Label.Text = 'ABB2600';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = 'HoseAttachment-PhotoRoom.png';
            app.PositionLabel_2 = uilabel(app.UIFigure);
            app.PositionLabel_2.Text = 'Position';
            app.Link1Slider_2Label = uilabel(app.UIFigure);
            app.Link1Slider_2Label.Text = 'Link 1';
            app.Link1Slider_2 = uislider(app.UIFigure);
            app.Link1Slider_2.Limits = [-3.8 -0.01];
            app.Link1Slider_2.ValueChangedFcn = createCallbackFcn(app, @Link1Slider_2ValueChanged, true);
            app.Link1Slider_2.Value = -0.01;
            app.Link2Slider_2Label = uilabel(app.UIFigure);
            app.Link2Slider_2Label.Text = 'Link 2';
            app.Link2Slider_2 = uislider(app.UIFigure);
            app.Link2Slider_2.Limits = [-180 180];
            app.Link2Slider_2.ValueChangedFcn = createCallbackFcn(app, @Link2Slider_2ValueChanged, true);
            app.Link3Slider_2Label = uilabel(app.UIFigure);
            app.Link3Slider_2Label.Text = 'Link 3';
            app.Link3Slider_2 = uislider(app.UIFigure);
            app.Link3Slider_2.Limits = [-95 155];
            app.Link3Slider_2.ValueChangedFcn = createCallbackFcn(app, @Link3Slider_2ValueChanged, true);
            app.Link4Slider_2Label = uilabel(app.UIFigure);
            app.Link4Slider_2Label.Text = 'Link 4';
            app.Link4Slider_2 = uislider(app.UIFigure);
            app.Link4Slider_2.Limits = [-180 75];
            app.Link4Slider_2.ValueChangedFcn = createCallbackFcn(app, @Link4Slider_2ValueChanged, true);
            app.Link5Slider_2Label = uilabel(app.UIFigure);
            app.Link5Slider_2Label.Text = 'Link 5';
            app.Link5Slider_2 = uislider(app.UIFigure);
            app.Link5Slider_2.Limits = [-400 400];
            app.Link5Slider_2.ValueChangedFcn = createCallbackFcn(app, @Link5Slider_2ValueChanged, true);
            app.Link6Slider_2Label = uilabel(app.UIFigure);
            app.Link6Slider_2Label.Text = 'Link 6';
            app.Link6Slider_2 = uislider(app.UIFigure);
            app.Link6Slider_2.Limits = [-120 120];
            app.Link6Slider_2.ValueChangedFcn = createCallbackFcn(app, @Link6Slider_2ValueChanged, true);
            app.Link7Slider_2Label = uilabel(app.UIFigure);
            app.Link7Slider_2Label.Text = 'Link 7';
            app.Link7Slider_2 = uislider(app.UIFigure);
            app.Link7Slider_2.Limits = [-400 400];
            app.Link7Slider_2.ValueChangedFcn = createCallbackFcn(app, @Link7Slider_2ValueChanged, true);
            app.RunbothButton = uibutton(app.UIFigure, 'push');
            app.RunbothButton.ButtonPushedFcn = createCallbackFcn(app, @RunbothButtonPushed, true);
            app.RunbothButton.Text = 'Run both';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotControllerGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end