classdef RobotControllerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        PositionLabel         matlab.ui.control.Label
        ZLabel                matlab.ui.control.Label
        YLabel                matlab.ui.control.Label
        XLabel                matlab.ui.control.Label
        EditField_9           matlab.ui.control.NumericEditField
        EditField_8           matlab.ui.control.NumericEditField
        EditField_7           matlab.ui.control.NumericEditField
        RunButton             matlab.ui.control.Button
        Image2                matlab.ui.control.Image
        TwinTechAutoSpaLabel  matlab.ui.control.Label
        ShutDownLabel         matlab.ui.control.Label
        ActiveLabel_2         matlab.ui.control.Label
        StopLabel             matlab.ui.control.Label
        Lamp_4                matlab.ui.control.Lamp
        Lamp_3                matlab.ui.control.Lamp
        Lamp_2                matlab.ui.control.Lamp
        RobotsButtonGroup     matlab.ui.container.ButtonGroup
        Image_2               matlab.ui.control.Image
        Image                 matlab.ui.control.Image
        ABB2600Button         matlab.ui.control.RadioButton
        UR10Button            matlab.ui.control.RadioButton
        EditField_6           matlab.ui.control.NumericEditField
        EditField_5           matlab.ui.control.NumericEditField
        EditField_4           matlab.ui.control.NumericEditField
        EditField_3           matlab.ui.control.NumericEditField
        EditField_2           matlab.ui.control.NumericEditField
        EditField             matlab.ui.control.NumericEditField
        EmergencyStopLabel    matlab.ui.control.Label
        Link6Slider           matlab.ui.control.Slider
        Link6SliderLabel      matlab.ui.control.Label
        Link5Slider           matlab.ui.control.Slider
        Link5Label            matlab.ui.control.Label
        Link4Slider           matlab.ui.control.Slider
        Link4SliderLabel      matlab.ui.control.Label
        Link3Slider           matlab.ui.control.Slider
        Link3SliderLabel      matlab.ui.control.Label
        Link2Slider           matlab.ui.control.Slider
        Link2SliderLabel      matlab.ui.control.Label
        Link1Slider           matlab.ui.control.Slider
        Link1SliderLabel      matlab.ui.control.Label
        Button                matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.Lamp_2.Color = [1,1,1];
            app.Lamp_3.Color = [1,1,1];
            set(app.RunButton,'Enable','off')

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
        if strcmpi(app.Button.Text,'Shut Down')
            app.Button.Text = 'STOP';
            app.RunButton.Text = 'Run';
            app.Lamp_2.Color = [1,0,0];
            app.Lamp_3.Color = [1,1,1];
        end
            
        if strcmpi(app.RunButton.Enable,'off')
            set(app.RunButton,'Enable','on')
            app.Lamp_2.Color = [1,1,1];
            app.Lamp_3.Color = [1,1,0];
            app.Lamp_4.Color = [1,1,1];
            app.Button.Text = 'Shut Down';
            app.RunButton.Text = 'Continue';
        end
        
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            plot(LinearUR10);
        end

        % Selection changed function: RobotsButtonGroup
        function RobotsButtonGroupSelectionChanged(app, event)
            if app.UR10Button.Value == 1
               r = LinearUR10;
            end

          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Robot Grid')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'button-161502_1280.png';
            app.Button.Text = '';
            app.Link1SliderLabel = uilabel(app.UIFigure);
            app.Link1SliderLabel.Text = 'Link 1';
            app.Link1Slider = uislider(app.UIFigure);
            app.Link2SliderLabel = uilabel(app.UIFigure);
            app.Link2SliderLabel.Text = 'Link 2';
            app.Link2Slider = uislider(app.UIFigure);
            app.Link3SliderLabel = uilabel(app.UIFigure);
            app.Link3SliderLabel.Text = 'Link 3';
            app.Link3Slider = uislider(app.UIFigure);
            app.Link4SliderLabel = uilabel(app.UIFigure);
            app.Link4SliderLabel.Text = 'Link 4';
            app.Link4Slider = uislider(app.UIFigure);
            app.Link5Label = uilabel(app.UIFigure);
            app.Link5Label.Text = 'Link 5';
            app.Link5Slider = uislider(app.UIFigure);
            app.Link6SliderLabel = uilabel(app.UIFigure);
            app.Link6SliderLabel.Text = 'Link 6';
            app.Link6Slider = uislider(app.UIFigure);
            app.EmergencyStopLabel = uilabel(app.UIFigure);
            app.EmergencyStopLabel.Text = 'Emergency Stop';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.RobotsButtonGroup = uibuttongroup(app.UIFigure);
            app.RobotsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RobotsButtonGroupSelectionChanged, true);
            app.RobotsButtonGroup.Title = 'Robots';
            app.UR10Button = uiradiobutton(app.RobotsButtonGroup);
            app.UR10Button.Text = 'UR10';
            app.UR10Button.Value = true;
            app.ABB2600Button = uiradiobutton(app.RobotsButtonGroup);
            app.ABB2600Button.Text = 'ABB2600';
            app.Image = uiimage(app.RobotsButtonGroup);
            app.Image.ImageSource = 'Scrub-Daddy-Emblem.png';
            app.Image_2 = uiimage(app.RobotsButtonGroup);
            app.Image_2.ImageSource = 'HoseAttachment-PhotoRoom.png';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_4 = uilamp(app.UIFigure);
            app.StopLabel = uilabel(app.UIFigure);
            app.StopLabel.Text = 'Stop';
            app.ActiveLabel_2 = uilabel(app.UIFigure);
            app.ActiveLabel_2.Text = 'Active';
            app.ShutDownLabel = uilabel(app.UIFigure);
            app.ShutDownLabel.Text = 'Shut Down';
            app.TwinTechAutoSpaLabel = uilabel(app.UIFigure);
            app.TwinTechAutoSpaLabel.Text = 'Twin Tech Auto Spa';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'LogoDroplets.png';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z';
            app.PositionLabel = uilabel(app.UIFigure);
            app.PositionLabel.Text = 'Position';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotControllerGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end